classdef base_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        xmlfileEditField        matlab.ui.control.EditField
        xmlfileEditFieldLabel   matlab.ui.control.Label
        DISCONNECTButton        matlab.ui.control.Button
        CONNECTButton           matlab.ui.control.Button
        BaudRateEditField       matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel  matlab.ui.control.Label
        COMPortEditField        matlab.ui.control.EditField
        COMPortEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % My public properties
        s % Serial port object
        dialect
        mavlink
        t

        pool
        queue_to_worker
        queue_to_gui
        worker
    end
    
    methods (Access = private)
        
        
        function rx(app)
            data = uint8([]);
            if app.s.NumBytesAvailable > 0
                data = read(app.s, app.s.NumBytesAvailable, "uint8");
                data = uint8(data);
                send(app.queue_to_worker, data);
            end
            
            % % disp(app.rx_speed_calc(length(data)))
        end
        
        function speed = rx_speed_calc(app, bytes)
            window_samples = 5;
            persistent buffer index count;
            if isempty(buffer)
                buffer = zeros(1, window_samples);
                index = 0;
                count = 0;
            end
            index = mod(index, window_samples) + 1;
            buffer(index) = bytes;
            count = min(count + 1, window_samples);
            speed = sum(buffer(1:count)) / (count * app.t.Period);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONNECTButton
        function connectSerial(app, event)
            port = char(app.COMPortEditField.Value);
            baud = double(app.BaudRateEditField.Value);
            xmlPath = char(app.xmlfileEditField.Value);
            
            try
                app.dialect = mavlinkdialect(xmlPath);
                app.mavlink = mavlinkio(app.dialect);
                disp(['Dialect ', xmlPath, ' loaded']);
            catch e
                uialert(app.UIFigure, ['Error loading dialect: ', e.message], 'MAVLink Error');
            end
            
            try
                app.s = serialport(port, baud);
                disp(['Connected to ', port, ' at ', num2str(baud), ' baud']);
            catch e
                uialert(app.UIFigure, ['Error connecting: ', e.message], 'Connection Error');
            end

            try
                app.t = timer('ExecutionMode', 'fixedSpacing', ...
                          'Period', 0.02, ... % 20 ms
                          'TimerFcn', @(~,~) app.rx());
                start(app.t);
            catch e
                uialert(app.UIFigure, ['Error with a timer: ', e.message], 'Thread Error');
            end

            try
                % Preparation of the pool and the queues
                disp("Starting the thread worker...")
                app.pool = parpool('Threads',1); % teraz stwórz nową pulę
                app.queue_to_worker = parallel.pool.PollableDataQueue(Destination="any");
                app.queue_to_gui = parallel.pool.DataQueue;
                % Callback for handling queue_to_gui
                afterEach(app.queue_to_gui, @(data) disp("Odebrano z workera: " + string(data)));
                % Actual start of the worker thread
                app.worker = parfeval(app.pool, @rx_worker, 0, app.queue_to_worker, app.queue_to_gui);
                disp(['Started a thread worker for heavy tasks']);
            catch e
                uialert(app.UIFigure, ['Error starting a worker: ', e.message], 'Thread Error');
            end
              
        end

        % Button pushed function: DISCONNECTButton
        function disconnectSerial(app, event)
            % --- Timer ---
            try
                stop(app.t)
                delete(app.t)
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            
            % --- Port szeregowy ---
            port = char(app.COMPortEditField.Value);
            try
                delete(app.s)
                disp(['Serial port ' port ' disconnected']);
            catch e
                uialert(app.UIFigure, ['Error disconnecting: ', e.message], 'Connection Error');
            end

            try
                cancel(app.worker);
                delete(app.worker);
                delete(app.pool);
                disp(['Stopped a thread worker']);
            catch e
                uialert(app.UIFigure, ['Error canceling a worker: ', e.message], 'Thread Error');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Placeholder = 'COM';
            app.COMPortEditField.Value = 'COM17';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%d';
            app.BaudRateEditField.Value = 115200;
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @connectSerial, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectSerial, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.xmlfileEditFieldLabel = uilabel(app.UIFigure);
            app.xmlfileEditFieldLabel.Text = '.xml file';
            app.xmlfileEditField = uieditfield(app.UIFigure, 'text');
            app.xmlfileEditField.Placeholder = './mavmc.xml';
            app.xmlfileEditField.Value = './mavmc.xml';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end